classdef ctsgui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ctsguiUIFigure                 matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        ModelTab                       matlab.ui.container.Tab
        FilamentsCheckBox              matlab.ui.control.CheckBox
        UpdateParametersButton         matlab.ui.control.Button
        ImportLayersButton             matlab.ui.control.Button
        IterationsEditField            matlab.ui.control.EditField
        IterationsEditFieldLabel       matlab.ui.control.Label
        ofLayersEditField              matlab.ui.control.NumericEditField
        ofLayersEditFieldLabel         matlab.ui.control.Label
        PixelSizeangEditField_2        matlab.ui.control.NumericEditField
        PixelSizeangEditField_2Label   matlab.ui.control.Label
        VesicleEditField               matlab.ui.control.NumericEditField
        VesicleEditFieldLabel          matlab.ui.control.Label
        ComputingModelLamp             matlab.ui.control.Lamp
        ComputingModelLampLabel        matlab.ui.control.Label
        FidDiameternmEditField         matlab.ui.control.NumericEditField
        FidDiameternmLabel             matlab.ui.control.Label
        ThicknessnmEditField           matlab.ui.control.NumericEditField
        ThicknessnmEditFieldLabel      matlab.ui.control.Label
        DiameternmEditField_2          matlab.ui.control.NumericEditField
        DiameternmEditField_2Label     matlab.ui.control.Label
        FidNumberEditField             matlab.ui.control.NumericEditField
        FidNumberEditFieldLabel        matlab.ui.control.Label
        ParticleDensity01EditField     matlab.ui.control.NumericEditField
        ParticleDensity01EditFieldLabel  matlab.ui.control.Label
        ZEditField                     matlab.ui.control.NumericEditField
        ZEditFieldLabel                matlab.ui.control.Label
        YEditField                     matlab.ui.control.NumericEditField
        YEditFieldLabel                matlab.ui.control.Label
        XEditField                     matlab.ui.control.NumericEditField
        XEditFieldLabel                matlab.ui.control.Label
        ConstraintButtonGroup          matlab.ui.container.ButtonGroup
        tubeButton                     matlab.ui.control.ToggleButton
        boxButton                      matlab.ui.control.ToggleButton
        sidesButton                    matlab.ui.control.ToggleButton
        noneButton                     matlab.ui.control.ToggleButton
        MembraneCheckBox               matlab.ui.control.CheckBox
        FiducialsCheckBox              matlab.ui.control.CheckBox
        CarbonHoleCheckBox             matlab.ui.control.CheckBox
        VitreousIceCheckBox            matlab.ui.control.CheckBox
        CTSModelLabel                  matlab.ui.control.Label
        OpenFilesin3dmodButton         matlab.ui.control.Button
        GenerateModelButton            matlab.ui.control.Button
        VolumeDimensionspixelsLabel    matlab.ui.control.Label
        SimulateTab                    matlab.ui.container.Tab
        Label                          matlab.ui.control.Label
        TiltSchemeLabel                matlab.ui.control.Label
        DoseEditField                  matlab.ui.control.NumericEditField
        DoseSymmetricCheckBox          matlab.ui.control.CheckBox
        TiltError01EditField           matlab.ui.control.NumericEditField
        TiltError01EditFieldLabel      matlab.ui.control.Label
        EnvelopeSigmaEditField         matlab.ui.control.NumericEditField
        EnvelopeSigmaEditFieldLabel    matlab.ui.control.Label
        AbberationmmEditField          matlab.ui.control.NumericEditField
        AbberationmmEditFieldLabel     matlab.ui.control.Label
        VoltagekVEditField             matlab.ui.control.NumericEditField
        VoltagekeVLabel                matlab.ui.control.Label
        CTFParametersLabel             matlab.ui.control.Label
        LoadedModelTextArea            matlab.ui.control.TextArea
        LoadedModelTextAreaLabel       matlab.ui.control.Label
        ImportModelFileButton          matlab.ui.control.Button
        SuffixTextArea                 matlab.ui.control.TextArea
        SuffixTextAreaLabel            matlab.ui.control.Label
        SimulateLamp                   matlab.ui.control.Lamp
        SimulateLampLabel              matlab.ui.control.Label
        OpenFilesin3dmodButton_2       matlab.ui.control.Button
        ImagingParametersLabel         matlab.ui.control.Label
        GenerateFilesButton            matlab.ui.control.Button
        CTSSimulateLabel               matlab.ui.control.Label
        DefocusmicronsEditField        matlab.ui.control.NumericEditField
        DefocusmicronsEditFieldLabel   matlab.ui.control.Label
        RadiationDamageEditField       matlab.ui.control.NumericEditField
        RadiationDamageEditFieldLabel  matlab.ui.control.Label
        TotalDoseeA2EditField          matlab.ui.control.NumericEditField
        TotalDoseeA2EditFieldLabel     matlab.ui.control.Label
        XEditFieldLabel_4              matlab.ui.control.Label
        XEditFieldLabel_3              matlab.ui.control.Label
        XEditFieldLabel_2              matlab.ui.control.Label
        TiltParametersLabel            matlab.ui.control.Label
        ZEditField_2                   matlab.ui.control.NumericEditField
        YEditField_2                   matlab.ui.control.NumericEditField
        XEditField_2                   matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        %model properties
        xsize =100;
        ysize = 100;
        zsize = 100;
        pixsize = 6.6
        pixsizetmp = 6.6;
        pdensity = .4
        vitcbox = 0
        constraint = '   '
        ice = 1
        membrane = 0
        carbon = 1
        fiducials = 0
        diameter = 0
        thickness = 0
        fidnum = 0
        fiddia = 50
        commandmod
        commandsim
        distractbool = 0
        distract = 'none'
        particlesadded
        ts = 7
        particlevol
        distractvol
        index
        modelparam
        layers = 1
        iterations = 1000

        % simulate properties
        max = 60
        min = -60
        tilt = 2
        totaldose = 100
        raddamage = 1
        defocus = -5
        tiltscheme = 'symmetric'
        splitang = 0
        model
        filename
        pathname
        path
        param
        in
        cs
        L
        k
        Dz
        B
        q
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: XEditField
        function XEditFieldValueChanged(app, event)
            app.xsize = app.XEditField.Value;
        end

        % Value changed function: YEditField
        function YEditFieldValueChanged(app, event)
            app.ysize = app.YEditField.Value;
        end

        % Value changed function: ZEditField
        function ZEditFieldValueChanged(app, event)
            app.zsize = app.ZEditField.Value;
        end

        % Callback function
        function PixelSizeangEditFieldValueChanged(app, event)
            app.pixsize = app.PixelSizeangEditField.Value;
            
            if app.pixsize ~= app.pixsizetmp %If Pixelsize is changed, disable Generate Model Button until Regen Model is selected.
                app.GenerateModelButton.Enable = "off";
            else
                app.GenerateModelButton.Enable = "on";
            end
            
        end

        % Value changed function: ParticleDensity01EditField
        function ParticleDensity01EditFieldValueChanged(app, event)
            app.pdensity = app.ParticleDensity01EditField.Value;
            
        end

        % Selection changed function: ConstraintButtonGroup
        function ConstraintButtonGroupSelectionChanged(app, event)

            %app.constraint = app.ConstraintButtonGroup.SelectedObject.Text;
            if strcmp(app.ConstraintButtonGroup.SelectedObject.Text, 'none') == 1
                app.constraint = '   ';
            else if strcmp(app.ConstraintButtonGroup.SelectedObject.Text, 'box') == 1
                    app.constraint = '&&&';
            else if strcmp(app.ConstraintButtonGroup.SelectedObject.Text, 'sides') == 1
                    app.constraint = '  &';
            else if strcmp(app.ConstraintButtonGroup.SelectedObject.Text, 'tube') == 1
                    app.constraint = ' &&';
            end
            end
            end
            end

            
        end

        % Value changed function: VitreousIceCheckBox
        function VitreousIceCheckBoxValueChanged(app, event)
             %if app.ice == 0
             %   app.ice = 1;
            %else
             %   app.ice = 0;
            %end
        end

        % Value changed function: MembraneCheckBox
        function MembraneCheckBoxValueChanged(app, event)
          
            if app.MembraneCheckBox.Value == 0
                app.VesicleEditField.Enable = "off";
                app.VesicleEditField.Enable = "off";
              
            else 
                
                app.VesicleEditField.Enable = "on";
                app.VesicleEditField.Enable = "on";
               
     
            end
        end

        % Value changed function: CarbonHoleCheckBox
        function CarbonHoleCheckBoxValueChanged(app, event)
            app.carbon = app.CarbonHoleCheckBox.Value;
            if app.carbon == 0
                app.DiameternmEditField_2.Enable = "off";
                app.ThicknessnmEditField.Enable = "off";
                app.diameter = 0;
                app.thickness = 0;
            else
                app.DiameternmEditField_2.Enable = "on";
                app.ThicknessnmEditField.Enable = "on";
                app.diameter = app.DiameternmEditField_2.Value;
                app.thickness = app.ThicknessnmEditField.Value;
            end
        end

        % Value changed function: FiducialsCheckBox
        function FiducialsCheckBoxValueChanged(app, event)
            app.fiducials = app.FiducialsCheckBox.Value;
            if app.fiducials == 0
                app.FidDiameternmEditField.Enable = "off";
                app.FidNumberEditField.Enable = "off";
                app.fidnum = 0;
            else 
                
                app.FidDiameternmEditField.Enable = "on";
                app.FidNumberEditField.Enable = "on";
                app.fidnum = app.FidNumberEditField.Value;
            end
        end

        % Value changed function: DiameternmEditField_2
        function DiameternmEditField_2ValueChanged(app, event)
            app.diameter = app.DiameternmEditField_2.Value;
            
        end

        % Value changed function: ThicknessnmEditField
        function ThicknessnmEditFieldValueChanged(app, event)
            app.thickness = app.ThicknessnmEditField.Value;
            
        end

        % Value changed function: FidNumberEditField
        function FidNumberEditFieldValueChanged(app, event)
            app.fidnum = app.FidNumberEditField.Value;
            
        end

        % Callback function
        function GenerateCMDButtonPushed(app, event)
           
            app.commandmod = "cts_model('gui', zeros("+app.xsize+ "," +app.ysize+ "," +app.zsize+"),"+num2str(app.pixsize)+", 'density'," + num2str(app.pdensity)+", 'grid',["+app.thickness+" "+app.diameter+"], 'beads',["+app.fidnum+" "+app.fiddia+"],';mem',"+app.membrane+",'constraint',"+app.constraint+",'ice',"+app.VitreousIceCheckBox.Value+", 'distract',"+app.distractvol+");";
            app.CommandTextArea.Value = app.commandmod
        end

        % Value changed function: FidDiameternmEditField
        function FidDiameternmEditFieldValueChanged(app, event)
            if app.fiddia ~= 50
                app.fiddia = (app.FidDiameternmEditField.Value * 10)/2;
            else
                app.fiddia = 50;
            end
            
        end

        % Button pushed function: GenerateModelButton
        function GenerateModelButtonPushed(app, event)
         app.ComputingModelLamp.Color = 'g';
         
         cts_model(zeros(app.xsize, app.ysize, app.zsize), app.modelparam);
         %app.modelparam

         app.ComputingModelLamp.Color = 'r';
        
        end

        % Callback function
        function UIAxesButtonDown(app, event)
       
        end

        % Callback function
        function SwitchValueChanged(app, event)
            
        end

        % Callback function
        function DistractorsCheckBoxValueChanged(app, event)
        if app.DistractorsCheckBox.Value == 0
            app.ImportedDistractorParticlesListBox.Enable = "off";
            app.ImportDistractorsButton.Enable = "off";
            app.RemoveButton_2.Enable = "off";
            app.DuplicateButton_2.Enable = "off";
            app.distract = 'none';
        else app.ImportedDistractorParticlesListBox.Enable = "on";
             app.ImportDistractorsButton.Enable = "on";
             app.distract = app.distractvol;
            
        end
        
        end

        % Value changed function: XEditField_2
        function XEditField_2ValueChanged(app, event)
            app.max= app.XEditField_2.Value;
            
        end

        % Value changed function: YEditField_2
        function YEditField_2ValueChanged(app, event)
            app.min= app.YEditField_2.Value;
            
        end

        % Value changed function: ZEditField_2
        function ZEditField_2ValueChanged(app, event)
            app.tilt = app.ZEditField_2.Value;
            
        end

        % Value changed function: TotalDoseeA2EditField
        function TotalDoseeA2EditFieldValueChanged(app, event)
            app.totaldose = app.TotalDoseeA2EditField.Value;
            
        end

        % Value changed function: RadiationDamageEditField
        function RadiationDamageEditFieldValueChanged(app, event)
            app.raddamage = app.RadiationDamageEditField.Value;
            
        end

        % Value changed function: DefocusmicronsEditField
        function DefocusmicronsEditFieldValueChanged(app, event)
            app.defocus = app.DefocusmicronsEditField.Value;
            
        end

        % Callback function
        function CommandTextArea_2ValueChanged(app, event)
            app.commandsim = app.CommandTextArea_2.Value;            
        end

        % Callback function
        function GenerateCMDButton_2Pushed(app, event)
            app.commandsim = "cts_simulate('gui',{'tilt', ["+app.min+" "+app.tilt+" "+app.max+"],'dose',"+app.totaldose+", 'raddamage',"+app.raddamage+", 'defocus', "+app.defocus+",'tiltscheme',"+app.tiltscheme+"});";
            app.CommandTextArea_2.Value = app.commandsim;
      
        end

        % Button pushed function: GenerateFilesButton
        function GenerateFilesButtonPushed(app, event)
        app.SimulateLamp.Color = 'g';
        app.SimulateLamp.Color = 'g';

         pause(.1);

         app.param = param_simulate('voltage', app.VoltagekVEditField.Value,'aberration',app.AbberationmmEditField.Value,'defocus',app.DefocusmicronsEditField.Value,...
             'sigma',app.EnvelopeSigmaEditField.Value,'dose', app.TotalDoseeA2EditField.Value, 'raddamage', app.RadiationDamageEditField.Value,'tilterr', app.TiltError01EditField.Value,...
            'tilt', [app.min app.tilt app.max], 'tiltscheme', app.tiltscheme);

         cts_simulate(app.path, app.param, 'suffix', app.SuffixTextArea.Value);
         app.SimulateLamp.Color = 'r';
        end

        % Callback function
        function TiltSchemeButtonGroupSelectionChanged(app, event)
     

        end

        % Callback function
        function AngleEditFieldValueChanged(app, event)
          
            app.splitang = app.AngleEditField.Value;
           
            app.tiltscheme = app.splitang;
        end

        % Callback function
        function TiltSchemeButtonGroup_2SelectionChanged(app, event)
    
      
         if strcmp(app.TiltSchemeButtonGroup_2.SelectedObject.Text,'Dose Symmetric') == 1
            
             app.AngleEditField.Enable = "off";
             app.tiltscheme = 'symmetric';
          
     
         else
             app.AngleEditField.Enable = "on";
             app.tiltscheme = app.splitang;


         end

        end

        % Callback function
        function TiltSchemeButtonGroup_2SizeChanged(app, event)
            position = app.TiltSchemeButtonGroup_2.Position;
            
        end

        % Button pushed function: OpenFilesin3dmodButton, 
        % ...and 1 other component
        function OpenFilesin3dmodButtonPushed(app, event)
                   
          cmd = '3dmod';
            
           system(cmd);
        end

        % Callback function
        function ParticlesAddedEditFieldValueChanged(app, event)
            value = app.ParticlesAddedEditField.Value;
            app.ParticlesPlacedGauge.Value = app.ParticlesPlacedGauge.Value + value;
        end

        % Callback function
        function TotalParticlesEditFieldValueChanged(app, event)
            value = app.TotalParticlesEditField.Value;
            app.ParticlesPlacedGauge.Limits = [0 value];
        end

        % Callback function
        function ViewParticlesButtonPushed(app, event)
            figure; volshow(app.ts.model.particles, 'BackgroundColor','c');
        end

        % Value changed function: VesicleEditField
        function VesicleEditFieldValueChanged(app, event)
            value = app.VesicleEditField.Value;
            
        end

        % Callback function
        function ImportDistractorsButtonPushed(app, event)
            app.distractvol = helper_input('gui',app.pixsize,1);
            app.ImportDistractorsButton.Text = 'Regen Distractors'; %rename button to 'Regen Distractors' after first use.
            app.pixsizetmp = app.PixelSizeangEditField.Value; %set pixel size tmp to current Edit Box value
            
            app.GenerateModelButton.Enable = "on"; %Enable Generate Model Button now that the pixlesize has been updated.
            app.ImportedDistractorParticlesListBox.Items = {}; %cleans our the list of old data.
            %app.ImportedDistractorParticlesListBox.Items = app.distractvol(1).file +" "+app.PixelSizeangEditField.Value+" (ang/pix)";
            for i = 1:size(app.distractvol,2)
            app.ImportedDistractorParticlesListBox.Items(end +1) = app.distractvol(i).file; 
            end
           % app.distract = app.distractvol;
            app.RemoveButton_2.Enable = 'on';
            app.DuplicateButton_2.Enable = 'on';
        end

        % Callback function
        function DuplicateButtonPushed(app, event)
          
            [~, app.index] = ismember(app.ImportedParticlesListBox.Value, app.ImportedParticlesListBox.Items);
            app.particlevol(app.index); % ismember searches for the first instance of Value within items list and reports back index
            app.particlevol(end+1) = app.particlevol(app.index); %particlevol struct is appended with duplicate copy
            app.ImportedParticlesListBox.Items(end +1) = app.particlevol(size(app.particlevol,2)).file; %listbox is updated
            
        end

        % Callback function
        function RemoveButtonPushed(app, event)
            [~, app.index] = ismember(app.ImportedParticlesListBox.Value, app.ImportedParticlesListBox.Items);
            app.particlevol(app.index); % ismember searches for the first instance of Value within items list and reports back index
            app.particlevol(app.index) = []; %particlevol struct is replaced with empty field
            app.ImportedParticlesListBox.Items(app.index) = [];
        end

        % Callback function
        function ChooseSuffixCheckBoxValueChanged(app, event)
           if app.ChooseSuffixCheckBox.Value == 1
                app.SuffixTextArea.Enable = "on";
           else
                app.SuffixTextArea.Enable = "off";
           end
        end

        % Button pushed function: ImportModelFileButton
        function ImportModelFileButtonPushed(app, event)
            [app.filename, app.pathname]=uigetfile({'*.mrc;*.mat'});
            app.path=strcat(app.pathname,app.filename);
      
            app.LoadedModelTextArea.Value = app.filename;
           
        end

        % Callback function
        function DuplicateButton_2Pushed(app, event)
            [~, app.index] = ismember(app.ImportedDistractorParticlesListBox.Value, app.ImportedDistractorParticlesListBox.Items);
            app.distractvol(app.index); % ismember searches for the first instance of Value within items list and reports back index
            app.distractvol(end+1) = app.distractvol(app.index); %particlevol struct is appended with duplicate copy
            app.ImportedDistractorParticlesListBox.Items(end +1) = app.distractvol(size(app.distractvol,2)).file; %listbox is updated
        end

        % Callback function
        function RemoveButton_2Pushed(app, event)
            [~, app.index] = ismember(app.ImportedDistractorParticlesListBox.Value, app.ImportedDistractorParticlesListBox.Items);
            app.distractvol(app.index); % ismember searches for the first instance of Value within items list and reports back index
            app.distractvol(app.index) = []; %particlevol struct is replaced with empty field
            app.ImportedDistractorParticlesListBox.Items(app.index) = [];
        end

        % Callback function
        function PlotCTFButtonPushed(app, event)
       
        end

        % Value changed function: DoseEditField
        function DoseEditFieldValueChanged(app, event)
            app.tiltscheme = app.DoseEditField.Value;
        end

        % Value changed function: DoseSymmetricCheckBox
        function DoseSymmetricCheckBoxValueChanged(app, event)
            if app.DoseSymmetricCheckBox.Value == 1
                app.DoseEditField.Enable = "off";
                app.tiltscheme = 'symetric';
            else if app.DoseSymmetricCheckBox.Value == 0
                app.DoseEditField.Enable ="on";
                DoseEditFieldValueChanged(app, event);
            end
            end
            
        end

        % Button pushed function: ImportLayersButton
        function ImportLayersButtonPushed(app, event)
            app.modelparam = param_model(app.PixelSizeangEditField_2.Value, 'density',app.pdensity, ...
             'grid',[app.thickness app.diameter], 'beads',[app.fidnum app.fiddia],'mem',app.VesicleEditField.Value,...
             'ice',app.VitreousIceCheckBox.Value,'filaments',app.FilamentsCheckBox.Value, 'layers', app.layers, 'constraint', app.constraint, 'iters', app.iterations);
            app.modelparam.iters
           
        end

        % Value changed function: ofLayersEditField
        function ofLayersEditFieldValueChanged(app, event)
            app.layers = app.ofLayersEditField.Value;
            
        end

        % Value changed function: IterationsEditField
        function IterationsEditFieldValueChanged(app, event)
            app.iterations = str2num(app.IterationsEditField.Value);
            
        end

        % Button pushed function: UpdateParametersButton
        function UpdateParametersButtonPushed(app, event)
         
            app.modelparam.pix = app.PixelSizeangEditField_2.Value;
            app.modelparam.filaments = app.FilamentsCheckBox.Value;
            app.modelparam.density = app.ParticleDensity01EditField.Value;
            app.modelparam.iters = str2num(app.IterationsEditField.Value);
            app.IterationsEditField.Value
            app.modelparam.constraint = app.constraint;
            app.modelparam.grid = [app.ThicknessnmEditField.Value app.DiameternmEditField_2.Value];
            app.modelparam.mem = app.VesicleEditField.Value;
            app.modelparam.beads = [app.FidNumberEditField.Value app.FidDiameternmEditField.Value];
            app.modelparam.ice = app.VitreousIceCheckBox.Value;
            app.modelparam


        end

        % Value changed function: FilamentsCheckBox
        function FilamentsCheckBoxValueChanged(app, event)
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ctsguiUIFigure = uifigure('Visible', 'off');
            app.ctsguiUIFigure.Name = 'ctsgui';
            app.ctsguiUIFigure.Tag = 'modsim';
            app.TabGroup = uitabgroup(app.ctsguiUIFigure);
            app.ModelTab = uitab(app.TabGroup);
            app.ModelTab.Title = 'Model';
            app.VolumeDimensionspixelsLabel = uilabel(app.ModelTab);
            app.VolumeDimensionspixelsLabel.Text = 'Volume Dimensions (pixels)';
            app.GenerateModelButton = uibutton(app.ModelTab, 'push');
            app.GenerateModelButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateModelButtonPushed, true);
            app.GenerateModelButton.Tooltip = {'Press Button to generate model after importing parameters.'};
            app.GenerateModelButton.Text = 'Generate Model';
            app.OpenFilesin3dmodButton = uibutton(app.ModelTab, 'push');
            app.OpenFilesin3dmodButton.ButtonPushedFcn = createCallbackFcn(app, @OpenFilesin3dmodButtonPushed, true);
            app.OpenFilesin3dmodButton.Text = 'Open Files in 3dmod';
            app.CTSModelLabel = uilabel(app.ModelTab);
            app.CTSModelLabel.Text = 'CTS - Model';
            app.VitreousIceCheckBox = uicheckbox(app.ModelTab);
            app.VitreousIceCheckBox.ValueChangedFcn = createCallbackFcn(app, @VitreousIceCheckBoxValueChanged, true);
            app.VitreousIceCheckBox.Tooltip = {'Toggles vitreous ice during model building.'};
            app.VitreousIceCheckBox.Text = 'Vitreous Ice';
            app.VitreousIceCheckBox.Value = true;
            app.CarbonHoleCheckBox = uicheckbox(app.ModelTab);
            app.CarbonHoleCheckBox.ValueChangedFcn = createCallbackFcn(app, @CarbonHoleCheckBoxValueChanged, true);
            app.CarbonHoleCheckBox.Tooltip = {'Toggles simulated carbon hole.'};
            app.CarbonHoleCheckBox.Text = ' Carbon Hole';
            app.FiducialsCheckBox = uicheckbox(app.ModelTab);
            app.FiducialsCheckBox.ValueChangedFcn = createCallbackFcn(app, @FiducialsCheckBoxValueChanged, true);
            app.FiducialsCheckBox.Tooltip = {'Toggles gold fiducial beads during model building.'};
            app.FiducialsCheckBox.Text = 'Fiducials';
            app.MembraneCheckBox = uicheckbox(app.ModelTab);
            app.MembraneCheckBox.ValueChangedFcn = createCallbackFcn(app, @MembraneCheckBoxValueChanged, true);
            app.MembraneCheckBox.Tooltip = {'Toggles membrane vesicles during model building.'};
            app.MembraneCheckBox.Text = 'Membrane';
            app.ConstraintButtonGroup = uibuttongroup(app.ModelTab);
            app.ConstraintButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ConstraintButtonGroupSelectionChanged, true);
            app.ConstraintButtonGroup.Tooltip = {'Selections apply boundary constraints during particle placement.'};
            app.ConstraintButtonGroup.Title = 'Constraint';
            app.noneButton = uitogglebutton(app.ConstraintButtonGroup);
            app.noneButton.Text = 'none';
            app.noneButton.Value = true;
            app.sidesButton = uitogglebutton(app.ConstraintButtonGroup);
            app.sidesButton.Text = 'sides';
            app.boxButton = uitogglebutton(app.ConstraintButtonGroup);
            app.boxButton.Text = 'box';
            app.tubeButton = uitogglebutton(app.ConstraintButtonGroup);
            app.tubeButton.Text = 'tube';
            app.XEditFieldLabel = uilabel(app.ModelTab);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.ModelTab, 'numeric');
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @XEditFieldValueChanged, true);
            app.XEditField.Tooltip = {'Final X dimension of model.'};
            app.XEditField.Value = 100;
            app.YEditFieldLabel = uilabel(app.ModelTab);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.ModelTab, 'numeric');
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @YEditFieldValueChanged, true);
            app.YEditField.Tooltip = {'Final Y dimension of model.'};
            app.YEditField.Value = 100;
            app.ZEditFieldLabel = uilabel(app.ModelTab);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.ModelTab, 'numeric');
            app.ZEditField.ValueChangedFcn = createCallbackFcn(app, @ZEditFieldValueChanged, true);
            app.ZEditField.Tooltip = {'Final Z dimension of model.'};
            app.ZEditField.Value = 100;
            app.ParticleDensity01EditFieldLabel = uilabel(app.ModelTab);
            app.ParticleDensity01EditFieldLabel.Text = 'Particle Density (0-1)';
            app.ParticleDensity01EditField = uieditfield(app.ModelTab, 'numeric');
            app.ParticleDensity01EditField.ValueChangedFcn = createCallbackFcn(app, @ParticleDensity01EditFieldValueChanged, true);
            app.ParticleDensity01EditField.Tooltip = {'Raise or lower overall particle density threshold.'};
            app.ParticleDensity01EditField.Value = 0.4;
            app.FidNumberEditFieldLabel = uilabel(app.ModelTab);
            app.FidNumberEditFieldLabel.Text = 'Fid. Number';
            app.FidNumberEditField = uieditfield(app.ModelTab, 'numeric');
            app.FidNumberEditField.ValueChangedFcn = createCallbackFcn(app, @FidNumberEditFieldValueChanged, true);
            app.FidNumberEditField.Enable = 'off';
            app.FidNumberEditField.Tooltip = {'Sets the number of attempted placements.'};
            app.DiameternmEditField_2Label = uilabel(app.ModelTab);
            app.DiameternmEditField_2Label.Tooltip = {''};
            app.DiameternmEditField_2Label.Text = 'Diameter (nm)';
            app.DiameternmEditField_2 = uieditfield(app.ModelTab, 'numeric');
            app.DiameternmEditField_2.ValueChangedFcn = createCallbackFcn(app, @DiameternmEditField_2ValueChanged, true);
            app.DiameternmEditField_2.Enable = 'off';
            app.DiameternmEditField_2.Tooltip = {'Sets diameter of hole.'};
            app.ThicknessnmEditFieldLabel = uilabel(app.ModelTab);
            app.ThicknessnmEditFieldLabel.Text = 'Thickness (nm)';
            app.ThicknessnmEditField = uieditfield(app.ModelTab, 'numeric');
            app.ThicknessnmEditField.ValueChangedFcn = createCallbackFcn(app, @ThicknessnmEditFieldValueChanged, true);
            app.ThicknessnmEditField.Enable = 'off';
            app.ThicknessnmEditField.Tooltip = {'Sets thickess of carbon layer.'};
            app.FidDiameternmLabel = uilabel(app.ModelTab);
            app.FidDiameternmLabel.Text = 'Fid. Diameter (nm)';
            app.FidDiameternmEditField = uieditfield(app.ModelTab, 'numeric');
            app.FidDiameternmEditField.ValueChangedFcn = createCallbackFcn(app, @FidDiameternmEditFieldValueChanged, true);
            app.FidDiameternmEditField.Enable = 'off';
            app.FidDiameternmEditField.Tooltip = {'Sets fiducial diameter.'};
            app.ComputingModelLampLabel = uilabel(app.ModelTab);
            app.ComputingModelLampLabel.Text = 'Computing Model';
            app.ComputingModelLamp = uilamp(app.ModelTab);
            app.VesicleEditFieldLabel = uilabel(app.ModelTab);
            app.VesicleEditFieldLabel.Text = 'Vesicle #';
            app.VesicleEditField = uieditfield(app.ModelTab, 'numeric');
            app.VesicleEditField.ValueChangedFcn = createCallbackFcn(app, @VesicleEditFieldValueChanged, true);
            app.VesicleEditField.Enable = 'off';
            app.VesicleEditField.Tooltip = {'# of different vesicles to  generate before placement.'};
            app.PixelSizeangEditField_2Label = uilabel(app.ModelTab);
            app.PixelSizeangEditField_2Label.Tooltip = {'When Value is changed, run Regen Particles to enable Generate Model.'};
            app.PixelSizeangEditField_2Label.Text = 'Pixel Size (ang)';
            app.PixelSizeangEditField_2 = uieditfield(app.ModelTab, 'numeric');
            app.PixelSizeangEditField_2.Tooltip = {'Sets the pixel size for imported particles and distractors, as well as the final pixel size of the tomogram. If the pixel size is changed, particles will need to be re-imported.'};
            app.PixelSizeangEditField_2.Value = 6.6;
            app.ofLayersEditFieldLabel = uilabel(app.ModelTab);
            app.ofLayersEditFieldLabel.Text = '# of Layers';
            app.ofLayersEditField = uieditfield(app.ModelTab, 'numeric');
            app.ofLayersEditField.ValueChangedFcn = createCallbackFcn(app, @ofLayersEditFieldValueChanged, true);
            app.ofLayersEditField.Tooltip = {'Enter the number of particle layers to import.'};
            app.ofLayersEditField.Value = 1;
            app.IterationsEditFieldLabel = uilabel(app.ModelTab);
            app.IterationsEditFieldLabel.Text = 'Iterations';
            app.IterationsEditField = uieditfield(app.ModelTab, 'text');
            app.IterationsEditField.ValueChangedFcn = createCallbackFcn(app, @IterationsEditFieldValueChanged, true);
            app.IterationsEditField.Tooltip = {'Enter number of iterations per layer, each seprated by a space.'};
            app.IterationsEditField.Value = '1000';
            app.ImportLayersButton = uibutton(app.ModelTab, 'push');
            app.ImportLayersButton.ButtonPushedFcn = createCallbackFcn(app, @ImportLayersButtonPushed, true);
            app.ImportLayersButton.Tooltip = {'Press to import particles and all selected parameters before generating model.'};
            app.ImportLayersButton.Text = 'Import Layers';
            app.UpdateParametersButton = uibutton(app.ModelTab, 'push');
            app.UpdateParametersButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateParametersButtonPushed, true);
            app.UpdateParametersButton.Tooltip = {'Press to import particles and all selected parameters before generating model.'};
            app.UpdateParametersButton.Text = 'Update Parameters';
            app.FilamentsCheckBox = uicheckbox(app.ModelTab);
            app.FilamentsCheckBox.ValueChangedFcn = createCallbackFcn(app, @FilamentsCheckBoxValueChanged, true);
            app.FilamentsCheckBox.Tooltip = {'Toggles vitreous ice during model building.'};
            app.FilamentsCheckBox.Text = 'Filaments';
            app.SimulateTab = uitab(app.TabGroup);
            app.SimulateTab.Title = 'Simulate';
            app.XEditField_2 = uieditfield(app.SimulateTab, 'numeric');
            app.XEditField_2.ValueChangedFcn = createCallbackFcn(app, @XEditField_2ValueChanged, true);
            app.XEditField_2.Tooltip = {'Maximum tilt angle in degrees.'};
            app.XEditField_2.Value = 60;
            app.YEditField_2 = uieditfield(app.SimulateTab, 'numeric');
            app.YEditField_2.ValueChangedFcn = createCallbackFcn(app, @YEditField_2ValueChanged, true);
            app.YEditField_2.Tooltip = {'Minimum tilt angle in degrees.'};
            app.YEditField_2.Value = -60;
            app.ZEditField_2 = uieditfield(app.SimulateTab, 'numeric');
            app.ZEditField_2.ValueChangedFcn = createCallbackFcn(app, @ZEditField_2ValueChanged, true);
            app.ZEditField_2.Tooltip = {'Tilt step in degrees.'};
            app.ZEditField_2.Value = 2;
            app.TiltParametersLabel = uilabel(app.SimulateTab);
            app.TiltParametersLabel.Text = 'Tilt Parameters';
            app.XEditFieldLabel_2 = uilabel(app.SimulateTab);
            app.XEditFieldLabel_2.Text = 'Max';
            app.XEditFieldLabel_3 = uilabel(app.SimulateTab);
            app.XEditFieldLabel_3.Text = 'Min';
            app.XEditFieldLabel_4 = uilabel(app.SimulateTab);
            app.XEditFieldLabel_4.Text = 'Tilt Step';
            app.TotalDoseeA2EditFieldLabel = uilabel(app.SimulateTab);
            app.TotalDoseeA2EditFieldLabel.Text = 'Total Dose (e/A^2)';
            app.TotalDoseeA2EditField = uieditfield(app.SimulateTab, 'numeric');
            app.TotalDoseeA2EditField.ValueChangedFcn = createCallbackFcn(app, @TotalDoseeA2EditFieldValueChanged, true);
            app.TotalDoseeA2EditField.Tooltip = {'Total cumulative dose across whole tilt series.'};
            app.TotalDoseeA2EditField.Value = 100;
            app.RadiationDamageEditFieldLabel = uilabel(app.SimulateTab);
            app.RadiationDamageEditFieldLabel.Text = 'Radiation Damage';
            app.RadiationDamageEditField = uieditfield(app.SimulateTab, 'numeric');
            app.RadiationDamageEditField.ValueChangedFcn = createCallbackFcn(app, @RadiationDamageEditFieldValueChanged, true);
            app.RadiationDamageEditField.Tooltip = {'Amount of radiation damage proportional to dose.'};
            app.RadiationDamageEditField.Value = 1;
            app.DefocusmicronsEditFieldLabel = uilabel(app.SimulateTab);
            app.DefocusmicronsEditFieldLabel.Text = 'Defocus (microns)';
            app.DefocusmicronsEditField = uieditfield(app.SimulateTab, 'numeric');
            app.DefocusmicronsEditField.ValueChangedFcn = createCallbackFcn(app, @DefocusmicronsEditFieldValueChanged, true);
            app.DefocusmicronsEditField.Tooltip = {'Enter defocus value.'};
            app.DefocusmicronsEditField.Value = -5;
            app.CTSSimulateLabel = uilabel(app.SimulateTab);
            app.CTSSimulateLabel.Text = 'CTS - Simulate';
            app.GenerateFilesButton = uibutton(app.SimulateTab, 'push');
            app.GenerateFilesButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateFilesButtonPushed, true);
            app.GenerateFilesButton.Tooltip = {'Generate tilt series, tomogram and groundtruth atlas.'};
            app.GenerateFilesButton.Text = 'Generate Files';
            app.ImagingParametersLabel = uilabel(app.SimulateTab);
            app.ImagingParametersLabel.Text = 'Imaging Parameters';
            app.OpenFilesin3dmodButton_2 = uibutton(app.SimulateTab, 'push');
            app.OpenFilesin3dmodButton_2.ButtonPushedFcn = createCallbackFcn(app, @OpenFilesin3dmodButtonPushed, true);
            app.OpenFilesin3dmodButton_2.Text = 'Open Files in 3dmod';
            app.SimulateLampLabel = uilabel(app.SimulateTab);
            app.SimulateLampLabel.Text = 'Simulate Lamp';
            app.SimulateLamp = uilamp(app.SimulateTab);
            app.SuffixTextAreaLabel = uilabel(app.SimulateTab);
            app.SuffixTextAreaLabel.Text = 'Suffix';
            app.SuffixTextArea = uitextarea(app.SimulateTab);
            app.SuffixTextArea.Tooltip = {'Enter to append suffix to filename.'};
            app.ImportModelFileButton = uibutton(app.SimulateTab, 'push');
            app.ImportModelFileButton.ButtonPushedFcn = createCallbackFcn(app, @ImportModelFileButtonPushed, true);
            app.ImportModelFileButton.Text = 'Import Model File';
            app.LoadedModelTextAreaLabel = uilabel(app.SimulateTab);
            app.LoadedModelTextAreaLabel.Text = 'Loaded Model';
            app.LoadedModelTextArea = uitextarea(app.SimulateTab);
            app.CTFParametersLabel = uilabel(app.SimulateTab);
            app.CTFParametersLabel.Text = 'CTF Parameters';
            app.VoltagekeVLabel = uilabel(app.SimulateTab);
            app.VoltagekeVLabel.Text = 'Voltage (kV)';
            app.VoltagekVEditField = uieditfield(app.SimulateTab, 'numeric');
            app.VoltagekVEditField.Tooltip = {'Enter Voltage in kV.'};
            app.VoltagekVEditField.Value = 300;
            app.AbberationmmEditFieldLabel = uilabel(app.SimulateTab);
            app.AbberationmmEditFieldLabel.Text = 'Abberation (mm)';
            app.AbberationmmEditField = uieditfield(app.SimulateTab, 'numeric');
            app.AbberationmmEditField.Tooltip = {'Enter spherical abberation in mm.'};
            app.AbberationmmEditField.Value = 2.7;
            app.EnvelopeSigmaEditFieldLabel = uilabel(app.SimulateTab);
            app.EnvelopeSigmaEditFieldLabel.Text = 'Envelope (Sigma)';
            app.EnvelopeSigmaEditField = uieditfield(app.SimulateTab, 'numeric');
            app.EnvelopeSigmaEditField.Tooltip = {'Determines CTF falloff.'};
            app.EnvelopeSigmaEditField.Value = 1;
            app.TiltError01EditFieldLabel = uilabel(app.SimulateTab);
            app.TiltError01EditFieldLabel.Text = 'Tilt Error (0-1)';
            app.TiltError01EditField = uieditfield(app.SimulateTab, 'numeric');
            app.TiltError01EditField.Tooltip = {'Degree of tilt angle variation.'};
            app.DoseSymmetricCheckBox = uicheckbox(app.SimulateTab);
            app.DoseSymmetricCheckBox.ValueChangedFcn = createCallbackFcn(app, @DoseSymmetricCheckBoxValueChanged, true);
            app.DoseSymmetricCheckBox.Text = 'Dose Symmetric';
            app.DoseSymmetricCheckBox.Value = true;
            app.DoseEditField = uieditfield(app.SimulateTab, 'numeric');
            app.DoseEditField.ValueChangedFcn = createCallbackFcn(app, @DoseEditFieldValueChanged, true);
            app.DoseEditField.Enable = 'off';
            app.TiltSchemeLabel = uilabel(app.SimulateTab);
            app.TiltSchemeLabel.Text = 'Tilt Scheme';
            app.Label = uilabel(app.SimulateTab);
            app.Label.Text = '------------------';
            % Show the figure after all components are created
            app.ctsguiUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ctsgui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ctsguiUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ctsguiUIFigure)
        end
    end
end